classdef PDS_TF_EXP1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        EXP1Tab                        matlab.ui.container.Tab
        NombrearchivoEditField_5       matlab.ui.control.EditField
        NombrearchivoEditField_5Label  matlab.ui.control.Label
        NombrearchivoEditField_4       matlab.ui.control.EditField
        NombrearchivoEditField_4Label  matlab.ui.control.Label
        NombrearchivoEditField_3       matlab.ui.control.EditField
        NombrearchivoEditField_3Label  matlab.ui.control.Label
        NombrearchivoEditField_2       matlab.ui.control.EditField
        NombrearchivoEditField_2Label  matlab.ui.control.Label
        NombrearchivoEditField         matlab.ui.control.EditField
        NombrearchivoEditFieldLabel    matlab.ui.control.Label
        ReproducirButton_5             matlab.ui.control.Button
        ReproducirButton_4             matlab.ui.control.Button
        FilteredIIRButtonGroup         matlab.ui.container.ButtonGroup
        SpectrogramButton_5            matlab.ui.control.RadioButton
        FaseButton_4                   matlab.ui.control.RadioButton
        MagSpectButton_7               matlab.ui.control.RadioButton
        GrabarButton_4                 matlab.ui.control.Button
        ID_OEditField                  matlab.ui.control.EditField
        ID_OEditFieldLabel             matlab.ui.control.Label
        ReproducirButton_3             matlab.ui.control.Button
        SaveMezcladoButton             matlab.ui.control.Button
        ReproducirButton_2             matlab.ui.control.Button
        SaveSilbidoButton              matlab.ui.control.Button
        ReproducirButton               matlab.ui.control.Button
        SaveFraseButton                matlab.ui.control.Button
        GenerarfiltradoButton          matlab.ui.control.Button
        IDEditField                    matlab.ui.control.EditField
        IDEditFieldLabel               matlab.ui.control.Label
        FilteredFIRButtonGroup         matlab.ui.container.ButtonGroup
        SpectrogramButton_4            matlab.ui.control.RadioButton
        FaseButton_3                   matlab.ui.control.RadioButton
        MagSpectButton_6               matlab.ui.control.RadioButton
        GrabarButton                   matlab.ui.control.Button
        FrecuenciasIdentificadasLabel  matlab.ui.control.Label
        IIRButtonGroup                 matlab.ui.container.ButtonGroup
        FaseButton_2                   matlab.ui.control.RadioButton
        MagSpectButton_5               matlab.ui.control.RadioButton
        TantoporunoButton_2            matlab.ui.control.RadioButton
        ImpulsoButton_2                matlab.ui.control.RadioButton
        FIRButtonGroup                 matlab.ui.container.ButtonGroup
        FaseButton                     matlab.ui.control.RadioButton
        MagSpectButton_4               matlab.ui.control.RadioButton
        TantoporunoButton              matlab.ui.control.RadioButton
        ImpulsoButton                  matlab.ui.control.RadioButton
        bwEditField                    matlab.ui.control.EditField
        bwEditFieldLabel               matlab.ui.control.Label
        f_supEditField                 matlab.ui.control.EditField
        f_supEditFieldLabel            matlab.ui.control.Label
        f_infEditField                 matlab.ui.control.EditField
        f_infEditFieldLabel            matlab.ui.control.Label
        FraseconsilbidoButtonGroup     matlab.ui.container.ButtonGroup
        SpectrogramButton_3            matlab.ui.control.RadioButton
        MagSpectButton_3               matlab.ui.control.RadioButton
        TiempoButton_3                 matlab.ui.control.RadioButton
        SilbidoButtonGroup             matlab.ui.container.ButtonGroup
        SpectrogramButton_2            matlab.ui.control.RadioButton
        MagSpectButton_2               matlab.ui.control.RadioButton
        TiempoButton_2                 matlab.ui.control.RadioButton
        FraseButtonGroup               matlab.ui.container.ButtonGroup
        SpectrogramButton              matlab.ui.control.RadioButton
        MagSpectButton                 matlab.ui.control.RadioButton
        TiempoButton                   matlab.ui.control.RadioButton
        UnirButton                     matlab.ui.control.Button
        GrabarSilbidoButton            matlab.ui.control.Button
        GrabarFraseButton              matlab.ui.control.Button
        TcEditField                    matlab.ui.control.EditField
        TcEditFieldLabel               matlab.ui.control.Label
        UIAxes7                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fs = 8000; %Frecuencia de muestreo
        r = 24; %Resolucion
        x1 = []; % Frase
        x2 = []; % Silbido
        x = []; % Combinado
        y_FIR = [] % Señal filtrada con FIR
        y_IIR = []; % Señal filtrada con IIR
        h = [];
        H = [];
        FREC = [];
        Hf = [];
        impulse = [];
        hab = [];
        HA = [];
        HAf = [];
    end
    
    methods (Access = public)
        
        function toggle_button(app, varargin)
            for i=1:length(varargin)
                button = varargin{i};
                state = get(button, 'Enable');
                set(button, 'Enable', ~state);
            end
        end
        
        function toggle_button_on(app, varargin)
            for i=1:length(varargin)
                button = varargin{i};
                set(button, 'Enable', 'on');
            end
        end
        
        function toggle_button_off(app, varargin)
            for i=1:length(varargin)
                button = varargin{i};
                set(button, 'Enable', 'off');
            end
        end
        
        function format_graphs(app, xlim, xticks, xlabel, ylim, yticks, ylabel, varargin)
            %xlim, xticks, ylim and yticks must be arrays containing arrays
            %xlabel, ylabel son un string array

            if length(varargin) == 1
                %Eje X
                disp('hola')
                disp(varargin{1})
                varargin{1}.XGrid = "on";
                varargin{1}.XLim = xlim;
                varargin{1}.XTick = xticks;
                varargin{1}.XLabel.String = xlabel;

                %Eje Y
                varargin{1}.YGrid = "on";
                varargin{1}.YLim = ylim;
                varargin{1}.YTick = yticks;
                varargin{1}.YLabel.String = ylabel;
                return
            end

            for i=1:length(varargin)
                %Eje X
                varargin{i}.XGrid = "on";
                varargin{i}.XLim = xlim(i, :);
                varargin{i}.XTick = xticks(i, :);
                varargin{i}.XLabel.String = xlabel(i);

                %Eje Y
                varargin{i}.YGrid = "on";
                varargin{i}.YLim = ylim(i, :);
                varargin{i}.YTick = yticks(i, :);
                varargin{i}.YLabel.String = ylabel(i);
            end
        end
        
        function results = spectrogramgraph(app, graph, data)
            Fs = app.fs;
            app.clear_graph(graph)
            tiempo_ventana = 30*1e-3;
            tiempo_traslape = 0;
            tam_ventana = Fs*tiempo_ventana;
            tam_traslape = Fs*tiempo_traslape;
            nfft = 2^nextpow2(tam_ventana);
            [S, F, T] = spectrogram(data, tam_ventana, tam_traslape, nfft, Fs, 'yaxis');

            Tc = width(data(1:end-1))/Fs;
            xaxis = data;
            xlim = [0 Tc];
            xticks = 0:0.5:Tc;
            xtitle = 'Tiempo (s)';
            yaxis = F;
            ylim = [0 max(F)];
            yticks = 0:1000:max(F);
            ytitle = 'Frecuencia (Hz)';
            graph.XScale = 'linear';
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)

            spectrogram(data, tam_ventana, tam_traslape, nfft, Fs, 'yaxis');
            imagesc(graph, T, F, log(1+abs(S)));
            set(graph, 'YDir', 'normal')
            axis square
            axis tight
            colorbar
        end
        
        function results = magspectgraph(app, graph, data)
            Fs = app.fs;
            app.clear_graph(graph)
            raw = data;
            Nx = length(data);
            X = fft(raw);                 % Transformación de Fourier
            X = X(1:Nx/2+1);            % Frecuencias positivas
            fx = (0:Nx/2)' * Fs/Nx;     % Dominio de frecuencia de la señal de audio

            xaxis = fx;
            % xlim = [.1 max(xaxis)];
            % xticks = 0:1000:max(xaxis);
            % xtitle = 'Frecuencia (Hz)';
            yaxis = abs(X);
            % ylim = [min(yaxis) max(yaxis)];
            % yticks = min(yaxis):max(yaxis);
            % ytitle = 'Amplitud';
            % app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)

            graph.XLim = [.1 4];
            graph.XTick = 2.^(-2:2);
            graph.XScale = 'log';
            graph.XTickLabel = 2.^(-2:2);
            graph.YLim = [-80 40];
            graph.YTick = -80:20:40;
            graph.XLabel.String = 'f (kHz)';
            graph.YLabel.String = 'Amplitud';
            grid on;
            plot(graph, xaxis*1e-3, 20*log10(yaxis));
            grid on;
        end
        
        function results = timegraph(app, graph, data)
            app.clear_graph(graph)
            Fs = app.fs;
            Tc = width(data(1:end-1))/Fs;
            xaxis = 0:1/Fs:Tc; 
            xlim = [0 Tc];
            xticks = 0:0.5:Tc;
            xtitle = 'Tiempo (s)';
            yaxis = data;
            ylim = [-1 1];
            yticks = -1:0.5:1;
            ytitle = 'Amplitud';

            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            graph.XScale = 'linear';
            graph.XTick = xticks;
            graph.XLim = xlim;
            grid on;
            plot(graph, xaxis, yaxis)
        end
        
        function clear_graph(app, varargin)
            cellfun(@cla, varargin)
        end
        
        function results = radio_graph_select(app, radio, graph, data)
            graphs = ["Tiempo", "MagSpect", "Spectrogram", "Fase"];
            choice = radio.SelectedObject.Text;
            [~ , idx] = ismember(choice, graphs);
            if idx == 1
                app.timegraph(graph, data)
            elseif idx == 2
                app.magspectgraph(graph, data)
            elseif idx == 3
                app.spectrogramgraph(graph, data)
            else
                app.fasegraph(graph, data)
            end
        end
        
        function [h, H, FREC, Hf] = FIR_rechazabanda(app, f_inf, f_sup)
            % Indicar de la frecuencia del tono f_inf, f_sup y bw
            %bw = f_sup - f_inf ;

            % Conversion a frecuencia normalizada
            theta_inf = (f_inf/app.fs)*2*pi;
            theta_sup = (f_sup/app.fs)*2*pi;
            %theta_bw = theta_sup - theta_inf;

            % Establezco el orden del filtro % CORREGIR PARA CASO ORDEN PAR
            N = 221;
            n = 0:N;

            % Calculo filtro prototipo
            ha = (sin(theta_inf*(n-N/2)))./(pi.*(n-N/2)); % Pasa bajas
            hb = ((-1).^(n)).*(sin((pi-theta_sup).*(n-N/2)))./(pi.*(n-N/2)); % Pasa altas
            w = blackman(N+1)';
            hp = ha + hb;
            h = hp .* w;

            % Aplico el filtro a x
            y_FIRR = filter(h, 1, app.x);
            app.y_FIR = y_FIRR;
            %audiowrite("Audios\x_FIR_filtered.wav", y_FIRR, app.fs)

            % Datos en frecuencia
            [HP, FREC] = freqz(hp, 1, 8192, 2*pi); % Filtro prototipo sin aventanear
            [W, ~] = freqz(w, 1, 8192, 2*pi); % Ventana
            [H, Hf] = freqz(h, 1, 8192, 2*pi); % Filtro aventaneado
        end
        
        function results = FIRfilter_radio_graph_select(app, radio, graph, data)
            graphs = ["Impulso", "MagSpect", "Tanto por uno", "Fase"];
            choice = radio.SelectedObject.Text;
            [~ , idx] = ismember(choice, graphs);
            if idx == 1
                app.FIRfilter_timegraph(graph, data)
            elseif idx == 2
                app.FIRfilter_magspectgraph(graph, data)
            elseif idx == 3
                app.FIRfilter_tantoporunograph(graph, data)
            else
                app.FIRfilter_fasegraph(graph, data)
            end
        end
        
        function results = FIRfilter_timegraph(app, graph, data)
            app.clear_graph(graph)
            Fs = app.fs;
            Tc = width(data(1:end-1));
            xaxis = 0:Tc; 
            xlim = [0 Tc];
            xticks = 0:10:Tc;
            xtitle = 'Tiempo (s)';
            yaxis = data;
            ylim = [-0.3 1];
            yticks = -0.3:0.5:1;
            ytitle = 'Amplitud';

            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            graph.XScale = 'linear';
            graph.XTick = xticks;
            graph.XLim = xlim;
            plot(graph, xaxis, yaxis)
            grid on;
        end
        
        function results = FIRfilter_magspectgraph(app, graph, data)
            app.clear_graph(graph)
            xaxis = app.Hf;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:3.5;
            xtitle = "MagSpect Filtro FIR";
            yaxis = 20*log10(abs(app.H));
            ylim = [min(yaxis) max(yaxis)];
            yticks = [-15:5:5];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, app.Hf, 20*log10(abs(app.H)));
        end
        
        function results = FIRfilter_tantoporunograph(app, graph, data)
            app.clear_graph(graph)
            xaxis = app.FREC;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:3.5;
            xtitle = "MagSpect Filtro FIR";
            yaxis = abs(app.H);
            ylim = [min(yaxis)  max(yaxis)];
            yticks = [0:0.5:1.0002];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, app.FREC, yaxis);
        end
        
        function results = FIRfilter_fasegraph(app, graph,  data)
            app.clear_graph(graph)
            xaxis = app.Hf;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:max(xaxis);
            xtitle = "MagSpect Filtro FIR";
            yaxis = angle(app.H);
            ylim = [min(yaxis)  max(yaxis)];
            yticks = [min(yaxis):2:max(yaxis)];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, xaxis, yaxis);
        end
        
        function results = IIRfilter_radio_graph_select(app, radio, graph, data)
            graphs = ["Impulso", "MagSpect", "Tanto por uno", "Fase"];
            choice = radio.SelectedObject.Text;
            [~ , idx] = ismember(choice, graphs);
            if idx == 1
                app.IIRfilter_timegraph(graph, data)
            elseif idx == 2
                app.IIRfilter_magspectgraph(graph, data)
            elseif idx == 3
                app.IIRfilter_tantoporunograph(graph, data)
            else
                app.IIRfilter_fasegraph(graph, data)
            end
        end
        
        function results = IIRfilter_timegraph(app, graph, data)
            app.clear_graph(graph)
            xaxis = 0:80; 
            xlim = [0 80];
            xticks = 0:10:80;
            xtitle = 'Tiempo (s)';
            yaxis = app.hab';
            ylim = [min(yaxis) max(yaxis)];
            yticks = -0.1:0.1:0.2;
            ytitle = 'Amplitud';

            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            graph.XScale = 'linear';
            graph.XTick = xticks;
            graph.XLim = xlim;
            plot(graph, xaxis, yaxis)
            grid on;
        end
        
        function [impulse, hab, HA, HAf] = IIR_pasabajos(app)
            % Pasabajos tc = tan(0.9425/2), N=8 FUNCIONA
            B = [5.6266e-06 4.5013e-05 1.5755e-04 3.1509e-04 3.9386e-04 3.1509e-04 1.5755e-04 4.5013e-05 5.6266e-06];
            A = [1 -5.3910 13.0240 -18.3415 16.4225 -9.5532 3.5201 -0.7501 0.0707];

            [HA, HAf] = freqz(B, A, 8192, 2*pi);

            impulse_N = 81;
            impulse = [1; zeros(impulse_N-1, 1)];
            hab = filter(B, A, impulse);

            app.y_IIR = filter(B, A, app.x);
            %audiowrite("Audios\x_IIR_filtered.wav", app.y_IIR, app.fs);
        end
        
        function results = IIRfilter_tantoporunograph(app, graph, data)
            app.clear_graph(graph)
            xaxis = app.HAf;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:3.5;
            xtitle = "MagSpect Filtro FIR";
            yaxis = abs(app.HA);
            ylim = [min(yaxis)  max(yaxis)];
            yticks = [0:0.5:1.05];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, xaxis, yaxis);
        end
        
        function results = IIRfilter_magspectgraph(app, graph, data)
            app.clear_graph(graph)
            xaxis = app.HAf;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:3.5;
            xtitle = "MagSpect Filtro FIR";
            yaxis = 20*log10(abs(app.HA));
            ylim = [min(yaxis) max(yaxis)];
            yticks = [-200:100:0];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, xaxis, yaxis);
        end
        
        function results = IIRfilter_fasegraph(app, graph,  data)
            app.clear_graph(graph)
            xaxis = app.HAf;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:max(xaxis);
            xtitle = "MagSpect Filtro FIR";
            yaxis = angle(app.HA);
            ylim = [min(yaxis)  max(yaxis)];
            yticks = [min(yaxis):2:max(yaxis)];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, xaxis, yaxis);
        end
        
        function results = fasegraph(app, graph,  data)
            app.clear_graph(graph)
            xaxis = app.HAf;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:max(xaxis);
            xtitle = "MagSpect Filtro FIR";
            yaxis = angle(app.HA);
            ylim = [min(yaxis)  max(yaxis)];
            yticks = [min(yaxis):2:max(yaxis)];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, xaxis, yaxis);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Borrar contenido de los graficos
            app.clear_graph(app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes5, app.UIAxes6) % Borro todos los graficos
        end

        % Value changed function: TcEditField
        function TcEditFieldValueChanged(app, event)
            value = app.TcEditField.Value;
        end

        % Button pushed function: GrabarFraseButton
        function GrabarFraseButtonPushed(app, event)
            Tc = str2double(app.TcEditField.Value);
            ID = str2double(app.IDEditField.Value);
            fs = app.fs;
            r = app.r;

            % Disable record and reproducir buttons mientras espero
            app.toggle_button_off(app.GrabarFraseButton)

            % Start recording
            audioobjx1 = grabar_audio(Tc, app.fs, app.r, ID);
            app.x1 = getaudiodata(audioobjx1)';

            % Recording finished
            app.toggle_button_on(app.GrabarFraseButton) % Pasa de disabled a enabled
        end

        % Button pushed function: GrabarSilbidoButton
        function GrabarSilbidoButtonPushed(app, event)
            Tc = str2double(app.TcEditField.Value);
            ID = str2double(app.IDEditField.Value);
            fs = app.fs;
            r = app.r;

            % Disable record and reproducir buttons mientras espero
            app.toggle_button_off(app.GrabarSilbidoButton)

            % Start recording
            audioobjx2 = grabar_audio(Tc, app.fs, app.r, ID);
            app.x2 = getaudiodata(audioobjx2)';

            % Recording finished
            app.toggle_button_on(app.GrabarSilbidoButton) % Pasa de disabled a enabled
        end

        % Button pushed function: UnirButton
        function UnirButtonPushed(app, event)
            app.x = app.x1 + app.x2;
            app.radio_graph_select(app.FraseButtonGroup, app.UIAxes, app.x1)
            app.radio_graph_select(app.SilbidoButtonGroup, app.UIAxes2, app.x2)
            app.radio_graph_select(app.FraseconsilbidoButtonGroup, app.UIAxes3, app.x)
        end

        % Selection changed function: FraseButtonGroup
        function FraseButtonGroupSelectionChanged(app, event)
            selectedButton = app.FraseButtonGroup.SelectedObject;
            app.radio_graph_select(app.FraseButtonGroup, app.UIAxes, app.x1)
        end

        % Selection changed function: SilbidoButtonGroup
        function SilbidoButtonGroupSelectionChanged(app, event)
            selectedButton = app.SilbidoButtonGroup.SelectedObject;
            app.radio_graph_select(app.SilbidoButtonGroup, app.UIAxes2, app.x2)
        end

        % Selection changed function: FraseconsilbidoButtonGroup
        function FraseconsilbidoButtonGroupSelectionChanged(app, event)
            selectedButton = app.FraseconsilbidoButtonGroup.SelectedObject;
            app.radio_graph_select(app.FraseconsilbidoButtonGroup, app.UIAxes3, app.x)
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            Fs = app.fs;
            r = app.r;
            ID_O = str2double(app.ID_OEditField.Value);
            Tc = (width(app.x1)-1)/Fs;
            app.toggle_button_off(app.ReproducirButton)
            player = audioplayer(app.x1, Fs, r, ID_O);
            play(player)
            pause(Tc)
            app.toggle_button_on(app.ReproducirButton)
        end

        % Button pushed function: ReproducirButton_2
        function ReproducirButton_2Pushed(app, event)
            Fs = app.fs;
            r = app.r;
            ID_O = str2double(app.ID_OEditField.Value);
            Tc = (width(app.x2)-1)/Fs;
            app.toggle_button_off(app.ReproducirButton_2)
            player = audioplayer(app.x2, Fs, r, ID_O);
            play(player)
            pause(Tc)
            app.toggle_button_on(app.ReproducirButton_2)
        end

        % Button pushed function: ReproducirButton_3
        function ReproducirButton_3Pushed(app, event)
            Fs = app.fs;
            r = app.r;
            ID_O = str2double(app.ID_OEditField.Value);
            Tc = (width(app.x)-1)/Fs;
            app.toggle_button_off(app.ReproducirButton_3)
            player = audioplayer(app.x, Fs, r, ID_O);
            play(player)
            pause(Tc)
            app.toggle_button_on(app.ReproducirButton_3)
        end

        % Button pushed function: SaveFraseButton
        function SaveFraseButtonPushed(app, event)
            name = app.NombrearchivoEditField.Value + ".wav";
            audiowrite(name, app.x1, app.fs);
        end

        % Button pushed function: SaveSilbidoButton
        function SaveSilbidoButtonPushed(app, event)
            name = app.NombrearchivoEditField_2.Value + ".wav";
            audiowrite(name, app.x2, app.fs);
        end

        % Button pushed function: SaveMezcladoButton
        function SaveMezcladoButtonPushed(app, event)
            name = app.NombrearchivoEditField_3.Value + ".wav";
            audiowrite(name, app.x, app.fs);
        end

        % Button pushed function: GenerarfiltradoButton
        function GenerarfiltradoButtonPushed(app, event)
            f_inf = str2double(app.f_infEditField.Value);
            f_sup = str2double(app.f_supEditField.Value);
            [h1, H1, FREC1, Hf1] = app.FIR_rechazabanda(f_inf, f_sup);
            app.h = h1;
            app.H = H1;
            app.FREC = FREC1;
            app.Hf = Hf1;

            [impulse1, hab1, HA1, HAf1] = app.IIR_pasabajos();
            app.impulse = impulse1;
            app.hab = hab1;
            app.HA = HA1;
            app.HAf = HAf1;

            app.radio_graph_select(app.FilteredFIRButtonGroup, app.UIAxes6, app.y_FIR)
            app.radio_graph_select(app.FilteredFIRButtonGroup, app.UIAxes7, app.y_IIR)
            app.FIRfilter_radio_graph_select(app.FIRButtonGroup, app.UIAxes4, app.h)
            app.IIRfilter_radio_graph_select(app.IIRButtonGroup, app.UIAxes5, app.hab)
        end

        % Selection changed function: FIRButtonGroup
        function FIRButtonGroupSelectionChanged(app, event)
            selectedButton = app.FIRButtonGroup.SelectedObject;
            app.FIRfilter_radio_graph_select(app.FIRButtonGroup, app.UIAxes4, app.h)
        end

        % Selection changed function: IIRButtonGroup
        function IIRButtonGroupSelectionChanged(app, event)
            selectedButton = app.IIRButtonGroup.SelectedObject;
            app.IIRfilter_radio_graph_select(app.IIRButtonGroup, app.UIAxes5, app.HA)
        end

        % Selection changed function: FilteredFIRButtonGroup
        function FilteredFIRButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilteredFIRButtonGroup.SelectedObject;
            app.radio_graph_select(app.FilteredFIRButtonGroup, app.UIAxes6, app.y_FIR)
        end

        % Selection changed function: FilteredIIRButtonGroup
        function FilteredIIRButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilteredIIRButtonGroup.SelectedObject;
            app.radio_graph_select(app.FilteredIIRButtonGroup, app.UIAxes7, app.y_IIR)
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            name = app.NombrearchivoEditField_4.Value + ".wav";
            audiowrite(name, app.y_FIR, app.fs);
        end

        % Button pushed function: GrabarButton_4
        function GrabarButton_4Pushed(app, event)
            name = app.NombrearchivoEditField_5.Value + ".wav";
            audiowrite(name, app.y_IIR, app.fs);
        end

        % Button pushed function: ReproducirButton_4
        function ReproducirButton_4Pushed(app, event)
            Fs = app.fs;
            r = app.r;
            ID_O = str2double(app.ID_OEditField.Value);
            Tc = (width(app.y_FIR)-1)/Fs;
            app.toggle_button_off(app.ReproducirButton_4)
            player = audioplayer(app.y_FIR, Fs, r, ID_O);
            play(player)
            pause(Tc)
            app.toggle_button_on(app.ReproducirButton_4)
        end

        % Button pushed function: ReproducirButton_5
        function ReproducirButton_5Pushed(app, event)
            Fs = app.fs;
            r = app.r;
            ID_O = str2double(app.ID_OEditField.Value);
            Tc = (width(app.y_IIR)-1)/Fs;
            app.toggle_button_off(app.ReproducirButton_5)
            player = audioplayer(app.x1, Fs, r, ID_O);
            play(player)
            pause(Tc)
            app.toggle_button_on(app.ReproducirButton_5)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EXP1Tab = uitab(app.TabGroup);
            app.EXP1Tab.Title = 'EXP1';
            app.UIAxes = uiaxes(app.EXP1Tab);
            title(app.UIAxes, 'Frase')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.EXP1Tab);
            title(app.UIAxes2, 'Silbido')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.EXP1Tab);
            title(app.UIAxes3, 'Frase con silbido')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.EXP1Tab);
            title(app.UIAxes4, 'FIR')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.EXP1Tab);
            title(app.UIAxes5, 'IIR')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.EXP1Tab);
            title(app.UIAxes6, 'Señal filtrada FIR')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.EXP1Tab);
            title(app.UIAxes7, 'Señal filtrada IIR')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.TcEditFieldLabel = uilabel(app.EXP1Tab);
            app.TcEditFieldLabel.Text = 'Tc';
            app.TcEditField = uieditfield(app.EXP1Tab, 'text');
            app.TcEditField.ValueChangedFcn = createCallbackFcn(app, @TcEditFieldValueChanged, true);
            app.GrabarFraseButton = uibutton(app.EXP1Tab, 'push');
            app.GrabarFraseButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarFraseButtonPushed, true);
            app.GrabarFraseButton.Text = 'Grabar Frase';
            app.GrabarSilbidoButton = uibutton(app.EXP1Tab, 'push');
            app.GrabarSilbidoButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarSilbidoButtonPushed, true);
            app.GrabarSilbidoButton.Text = 'Grabar Silbido';
            app.UnirButton = uibutton(app.EXP1Tab, 'push');
            app.UnirButton.ButtonPushedFcn = createCallbackFcn(app, @UnirButtonPushed, true);
            app.UnirButton.Text = 'Unir';
            app.FraseButtonGroup = uibuttongroup(app.EXP1Tab);
            app.FraseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FraseButtonGroupSelectionChanged, true);
            app.FraseButtonGroup.Title = 'Frase';
            app.TiempoButton = uiradiobutton(app.FraseButtonGroup);
            app.TiempoButton.Text = 'Tiempo';
            app.TiempoButton.Value = true;
            app.MagSpectButton = uiradiobutton(app.FraseButtonGroup);
            app.MagSpectButton.Text = 'MagSpect';
            app.SpectrogramButton = uiradiobutton(app.FraseButtonGroup);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.SilbidoButtonGroup = uibuttongroup(app.EXP1Tab);
            app.SilbidoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SilbidoButtonGroupSelectionChanged, true);
            app.SilbidoButtonGroup.Title = 'Silbido';
            app.TiempoButton_2 = uiradiobutton(app.SilbidoButtonGroup);
            app.TiempoButton_2.Text = 'Tiempo';
            app.TiempoButton_2.Value = true;
            app.MagSpectButton_2 = uiradiobutton(app.SilbidoButtonGroup);
            app.MagSpectButton_2.Text = 'MagSpect';
            app.SpectrogramButton_2 = uiradiobutton(app.SilbidoButtonGroup);
            app.SpectrogramButton_2.Text = 'Spectrogram';
            app.FraseconsilbidoButtonGroup = uibuttongroup(app.EXP1Tab);
            app.FraseconsilbidoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FraseconsilbidoButtonGroupSelectionChanged, true);
            app.FraseconsilbidoButtonGroup.Title = 'Frase con silbido';
            app.TiempoButton_3 = uiradiobutton(app.FraseconsilbidoButtonGroup);
            app.TiempoButton_3.Text = 'Tiempo';
            app.TiempoButton_3.Value = true;
            app.MagSpectButton_3 = uiradiobutton(app.FraseconsilbidoButtonGroup);
            app.MagSpectButton_3.Text = 'MagSpect';
            app.SpectrogramButton_3 = uiradiobutton(app.FraseconsilbidoButtonGroup);
            app.SpectrogramButton_3.Text = 'Spectrogram';
            app.f_infEditFieldLabel = uilabel(app.EXP1Tab);
            app.f_infEditFieldLabel.Text = 'f_inf';
            app.f_infEditField = uieditfield(app.EXP1Tab, 'text');
            app.f_supEditFieldLabel = uilabel(app.EXP1Tab);
            app.f_supEditFieldLabel.Text = 'f_sup';
            app.f_supEditField = uieditfield(app.EXP1Tab, 'text');
            app.bwEditFieldLabel = uilabel(app.EXP1Tab);
            app.bwEditFieldLabel.Text = 'bw';
            app.bwEditField = uieditfield(app.EXP1Tab, 'text');
            app.FIRButtonGroup = uibuttongroup(app.EXP1Tab);
            app.FIRButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FIRButtonGroupSelectionChanged, true);
            app.FIRButtonGroup.Title = 'FIR';
            app.ImpulsoButton = uiradiobutton(app.FIRButtonGroup);
            app.ImpulsoButton.Text = 'Impulso';
            app.ImpulsoButton.Value = true;
            app.TantoporunoButton = uiradiobutton(app.FIRButtonGroup);
            app.TantoporunoButton.Text = 'Tanto por uno';
            app.MagSpectButton_4 = uiradiobutton(app.FIRButtonGroup);
            app.MagSpectButton_4.Text = 'MagSpect';
            app.FaseButton = uiradiobutton(app.FIRButtonGroup);
            app.FaseButton.Text = 'Fase';
            app.IIRButtonGroup = uibuttongroup(app.EXP1Tab);
            app.IIRButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IIRButtonGroupSelectionChanged, true);
            app.IIRButtonGroup.Title = 'IIR';
            app.ImpulsoButton_2 = uiradiobutton(app.IIRButtonGroup);
            app.ImpulsoButton_2.Text = 'Impulso';
            app.ImpulsoButton_2.Value = true;
            app.TantoporunoButton_2 = uiradiobutton(app.IIRButtonGroup);
            app.TantoporunoButton_2.Text = 'Tanto por uno';
            app.MagSpectButton_5 = uiradiobutton(app.IIRButtonGroup);
            app.MagSpectButton_5.Text = 'MagSpect';
            app.FaseButton_2 = uiradiobutton(app.IIRButtonGroup);
            app.FaseButton_2.Text = 'Fase';
            app.FrecuenciasIdentificadasLabel = uilabel(app.EXP1Tab);
            app.FrecuenciasIdentificadasLabel.Text = 'Frecuencias Identificadas';
            app.GrabarButton = uibutton(app.EXP1Tab, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.FilteredFIRButtonGroup = uibuttongroup(app.EXP1Tab);
            app.FilteredFIRButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilteredFIRButtonGroupSelectionChanged, true);
            app.FilteredFIRButtonGroup.Title = 'Filtered FIR';
            app.MagSpectButton_6 = uiradiobutton(app.FilteredFIRButtonGroup);
            app.MagSpectButton_6.Text = 'MagSpect';
            app.MagSpectButton_6.Value = true;
            app.FaseButton_3 = uiradiobutton(app.FilteredFIRButtonGroup);
            app.FaseButton_3.Text = 'Fase';
            app.SpectrogramButton_4 = uiradiobutton(app.FilteredFIRButtonGroup);
            app.SpectrogramButton_4.Text = 'Spectrogram';
            app.IDEditFieldLabel = uilabel(app.EXP1Tab);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.EXP1Tab, 'text');
            app.GenerarfiltradoButton = uibutton(app.EXP1Tab, 'push');
            app.GenerarfiltradoButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarfiltradoButtonPushed, true);
            app.GenerarfiltradoButton.Text = 'Generar filtrado';
            app.SaveFraseButton = uibutton(app.EXP1Tab, 'push');
            app.SaveFraseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFraseButtonPushed, true);
            app.SaveFraseButton.Text = 'Save Frase';
            app.ReproducirButton = uibutton(app.EXP1Tab, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.SaveSilbidoButton = uibutton(app.EXP1Tab, 'push');
            app.SaveSilbidoButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSilbidoButtonPushed, true);
            app.SaveSilbidoButton.Text = 'Save Silbido';
            app.ReproducirButton_2 = uibutton(app.EXP1Tab, 'push');
            app.ReproducirButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_2Pushed, true);
            app.ReproducirButton_2.Text = 'Reproducir';
            app.SaveMezcladoButton = uibutton(app.EXP1Tab, 'push');
            app.SaveMezcladoButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMezcladoButtonPushed, true);
            app.SaveMezcladoButton.Text = 'Save Mezclado';
            app.ReproducirButton_3 = uibutton(app.EXP1Tab, 'push');
            app.ReproducirButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_3Pushed, true);
            app.ReproducirButton_3.Text = 'Reproducir';
            app.ID_OEditFieldLabel = uilabel(app.EXP1Tab);
            app.ID_OEditFieldLabel.Text = 'ID_O';
            app.ID_OEditField = uieditfield(app.EXP1Tab, 'text');
            app.GrabarButton_4 = uibutton(app.EXP1Tab, 'push');
            app.GrabarButton_4.ButtonPushedFcn = createCallbackFcn(app, @GrabarButton_4Pushed, true);
            app.GrabarButton_4.Text = 'Grabar';
            app.FilteredIIRButtonGroup = uibuttongroup(app.EXP1Tab);
            app.FilteredIIRButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilteredIIRButtonGroupSelectionChanged, true);
            app.FilteredIIRButtonGroup.Title = 'Filtered IIR';
            app.MagSpectButton_7 = uiradiobutton(app.FilteredIIRButtonGroup);
            app.MagSpectButton_7.Text = 'MagSpect';
            app.MagSpectButton_7.Value = true;
            app.FaseButton_4 = uiradiobutton(app.FilteredIIRButtonGroup);
            app.FaseButton_4.Text = 'Fase';
            app.SpectrogramButton_5 = uiradiobutton(app.FilteredIIRButtonGroup);
            app.SpectrogramButton_5.Text = 'Spectrogram';
            app.ReproducirButton_4 = uibutton(app.EXP1Tab, 'push');
            app.ReproducirButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_4Pushed, true);
            app.ReproducirButton_4.Text = 'Reproducir';
            app.ReproducirButton_5 = uibutton(app.EXP1Tab, 'push');
            app.ReproducirButton_5.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_5Pushed, true);
            app.ReproducirButton_5.Text = 'Reproducir';
            app.NombrearchivoEditFieldLabel = uilabel(app.EXP1Tab);
            app.NombrearchivoEditFieldLabel.Text = 'Nombre archivo';
            app.NombrearchivoEditField = uieditfield(app.EXP1Tab, 'text');
            app.NombrearchivoEditField_2Label = uilabel(app.EXP1Tab);
            app.NombrearchivoEditField_2Label.Text = 'Nombre archivo';
            app.NombrearchivoEditField_2 = uieditfield(app.EXP1Tab, 'text');
            app.NombrearchivoEditField_3Label = uilabel(app.EXP1Tab);
            app.NombrearchivoEditField_3Label.Text = 'Nombre archivo';
            app.NombrearchivoEditField_3 = uieditfield(app.EXP1Tab, 'text');
            app.NombrearchivoEditField_4Label = uilabel(app.EXP1Tab);
            app.NombrearchivoEditField_4Label.Text = 'Nombre archivo';
            app.NombrearchivoEditField_4 = uieditfield(app.EXP1Tab, 'text');
            app.NombrearchivoEditField_5Label = uilabel(app.EXP1Tab);
            app.NombrearchivoEditField_5Label.Text = 'Nombre archivo';
            app.NombrearchivoEditField_5 = uieditfield(app.EXP1Tab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PDS_TF_EXP1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end